classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SynchronizationPanel            matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        PFIchannelLabel                 matlab.ui.control.Label
        ClockingTab                     matlab.ui.container.Tab
        PFIchannelLabel_2               matlab.ui.control.Label
        SyncDAQsviaclockfromamasterDAQLabel  matlab.ui.control.Label
        DoClockingCheckBox              matlab.ui.control.CheckBox
        DAQsListBox                     matlab.ui.control.ListBox
        DAQsListBoxLabel                matlab.ui.control.Label
        ArmDataAcquisitionPanel         matlab.ui.container.Panel
        ArmDataAcquisitionSwitch        matlab.ui.control.Switch
        TriggerControlsPanel            matlab.ui.container.Panel
        TrigModeSwitch                  matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        ExtTrigTab                      matlab.ui.container.Tab
        SamplesposttriggerEditField     matlab.ui.control.NumericEditField
        SamplesposttriggerEditFieldLabel  matlab.ui.control.Label
        ClickTrigTab                    matlab.ui.container.Tab
        TriggerButton                   matlab.ui.control.StateButton
        RateTimeControlsPanel           matlab.ui.container.Panel
        ReadRateHzEditField             matlab.ui.control.NumericEditField
        ReadRateHzEditFieldLabel        matlab.ui.control.Label
        RecordTimesEditField            matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel       matlab.ui.control.Label
        PlotRateHzEditField             matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel        matlab.ui.control.Label
        DAQRateHzEditField              matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel         matlab.ui.control.Label
        FileSavingParametersPanel       matlab.ui.container.Panel
        FolderNote                      matlab.ui.control.Label
        FolderButton                    matlab.ui.control.Button
        ProjectFolderEditField          matlab.ui.control.EditField
        ProjectFolderEditFieldLabel     matlab.ui.control.Label
        FilenameoptionalEditField       matlab.ui.control.EditField
        FilenameoptionalEditFieldLabel  matlab.ui.control.Label
        mainPlot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dn      % Names of recording DAQs (used to name fields for everything)
        s       % DAQ session 
        data    % Acquired data
        name    % Filenames and folders
        channels% Input channel info
        sync    % Synchronization controls and info
        rate    % Relevant information for timing/rates
        plt     % Relevant objects for plotting (plotTimer, colors, etc)
        lh      % Listeners for input
        channelPanels
        fieldsToSave = {'RecordTimesEditField', 'PlotRateHzEditField', ...
                'DAQRateHzEditField', 'ReadRateHzEditField', 'ProjectFolderEditField', ...
                'FilenameoptionalEditField', 'TrigModeSwitch', 'SamplesposttriggerEditField', ...
                'DAQsListBox'};
        channelFieldsToSave = {'chanOn_', 'chanDisplay_', 'chanName_', 'toggleButton_'};
        % TODO: All sync settings save and load as well
    end

    methods (Access = private)
        % Grab data when available function
        function GrabData(app,event,info)
            dq = event.UserData;
            newlen = length(info.TimeStamps);
            app.data.(dq).time = [app.data.(dq).time(newlen+1:end); info.TimeStamps];
            app.data.(dq).data = [app.data.(dq).data(newlen+1:end, :); info.Data];
        end
        
    
        function plotTimerUpdateFcn(app,src,event)
            for dq = app.DAQsListBox.Value
                i = dq{1};
                for j = 1:app.channels.(i).howMany
                    if app.channels.(i).(['chanDisplay_',num2str(j)]).Value
                        set(app.channels.(i).(['line_',num2str(j)]), ...
                            'XData', app.data.fakeTime(1:length(app.data.(i).data)), ...
                            'YData', app.data.(i).data(:, j));
                    else
                        set(app.channels.(i).(['line_',num2str(j)]), 'XData', nan, 'YData', nan);
                    end
                end
                drawnow limitrate
            end
        end
    
        % TODO: Clean this up, right now these functions aren't
        % consistently used. Display state not used at all 
        function getChannelActiveState(app, daq)
            app.channels.(daq).active = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).active(i) = strcmp(app.channels.(daq).(['chanOn_',num2str(i)]).Value, 'On');
            end
        end
        function getChannelDisplayState(app, daq)
            app.channels.(daq).display = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).display(i) = app.channels.(daq).(['chanDisplay_',num2str(i)]).Value;
            end
        end

        function createChannelPanel(app, index, numInit)
            % Create panel 
            app.channelPanels.(app.dn{index}) = uipanel(app.UIFigure, ...
                'Title', [app.dn{index}, ' channels'], ...
                'Position', [45 + (215 + 10)*(index-1), 0, 215, 400], ...
                'Scrollable', 'on', ...
                'Tag', num2str(index));
            % Create DAQ name field
            app.channels.(app.dn{index}).DAQNameField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'text', ...
                'Position', [0,360,80,22], ...
                'Value', app.dn{index}, ...
                'ValueChangedFcn', createCallbackFcn(app, @DAQNameValueChangedFcn, true), ...
                'Tooltip', {'Name of this DAQ','Data will be saved under file with this name'});
            % Create "Number of channels" numeric field
            app.channels.(app.dn{index}).NumberofChannelsEditField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'numeric', ...
                'Position', [135,335,31,22], ...
                'Value', numInit, ...
                'ValueChangedFcn', createCallbackFcn(app, @ChangeNumChannelsFcn, true));
            % Create trigger selection button group
            height = 25*numInit + 10 + 2*app.channels.trigOffset;
            app.channels.(app.dn{index}).TrigButtonGroup = uibuttongroup(app.channelPanels.(app.dn{index}), ...
                'Position', [177, 400-height-54, 25, height], 'Title', 'Trig', 'FontSize', 10, ...
                'AutoResizeChildren', 'off');
            % Create global check or uncheck all box
            app.channels.(app.dn{index}).globalCheck = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 320, 25, 25], ...
                    'Value', 1, 'Text', '', ...
                    'ValueChangedFcn', createCallbackFcn(app, @CheckBoxValueChanged, true), ...
                    'Tooltip', 'Check or uncheck display status for all channels on this DAQ');
            % Create each channel
            for i = 1:numInit
                app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                    'Items', {'Off', 'On'}, 'Value', 'On', ...
                    'Position', [25, 308 - 25*(i-1), 23, 10]);
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 300 - 25*(i-1), 25, 25], ...
                    'Value', 1, 'Text', '');
                app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                    'text', 'Value', ['ch',num2str(i)], ...
                    'Position', [102, 300 - 25*(i-1), 69, 20]);
                app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(numInit-i), 16, 16], 'Text', '');
            end
            % Create labels
            app.channels.(app.dn{index}).NumberofChannelsEditFieldLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Number of Channels:', 'Position', [9,337,119,22]);
            app.channels.(app.dn{index}).EnableLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Enable', 'Position', [22,318,42,22], 'FontSize', 10);
            app.channels.(app.dn{index}).ChannelNameLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Channel Name', 'Position', [100,318,85,22], 'FontSize', 10);
            % Note how many channels
            app.channels.(app.dn{index}).howMany = numInit;

            % Alter sync controls
            app.sync.(app.dn{index}).MasterButton = uitogglebutton(app.sync.MasterButtonGroup, ...
                'Position', [0, 155 - 25*(index-1), 50, 22], 'Text', app.dn{index}, 'Tag', app.dn{index});
            app.sync.(app.dn{index}).PFInum = uieditfield(app.StartTab, ...
                'numeric', 'Position', [66, 155 - 25*(index-1), 72, 22], 'Value', 0);
            app.sync.(app.dn{index}).clockPFInum = uieditfield(app.ClockingTab, ...
                'numeric', 'Position', [66, 115 - 25*(index-1), 72, 22], 'Value', 1);
        end

        % Function to manually move trigger select buttons vertically 
        function resizeTrigButtons(app, panel, ncurrent, ntotal)
            for i = 1:ncurrent
                app.channels.(panel).(['toggleButton_',num2str(i)]).Position(2) = app.channels.trigOffset+25*(ntotal-i);
            end
        end


        % Function to save data, restart DAQ listeners and other processes,
        % reset app state for next recording
        function stopRecordingReset(app)
            for dq = app.DAQsListBox.Value
                i = dq{1};
                % Stop sessions, delete listeners
                stop(app.s.(i))
                delete(app.lh.(i))
                % Get channel names to save
                app.name.(i).chanNames = cell(1, app.channels.(i).howMany + 1);
                app.name.(i).chanNames{1} = 'time';
                for j = 1:app.channels.(i).howMany
                    app.name.(i).chanNames{j+1} = app.channels.(i).(['chanName_',num2str(j)]).Value;
                end
                % TODO: Deal with different types of triggering
                % Write data to file
                % TODO: better way than this wasteful method
                savedata = struct();
                savedata.data = [app.data.(i).time, app.data.(i).data];
                savedata.channelNames = app.name.(i).chanNames;
                save(fullfile(app.name.path, app.name.(i).filename), '-struct', 'savedata', 'data', 'channelNames')
                % Re-enable controls
                app.channels.(i).TrigButtonGroup.Enable = 'On';
                app.channels.(i).NumberofChannelsEditField.Enable = 'On';
                app.channels.(i).DAQNameField.Enable = 'On';
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'On';
                    % Kill plot line handles for each channel
                    delete(app.channels.(i).(['line_',num2str(j)]));
                end
                app.sync.(i).PFInum.Enable = 'On';
                app.sync.(i).clockPFInum.Enable = 'On';
                % Clear out, re-declare matrices and indices
                app.data.(i).time = [];
                app.data.(i).data = [];

            end
            % Stop Plot Timers
            if strcmp(get(app.plt.plotTimer,'Running'),'on')
                stop(app.plt.plotTimer)
            end
            % Reset GUI, re-enable everything
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            app.ProjectFolderEditField.Enable = 'On';
            app.FilenameoptionalEditField.Enable = 'On';
            app.FolderButton.Enable = 'On';
            app.DAQRateHzEditField.Enable = 'On';
            app.PlotRateHzEditField.Enable = 'On';
            app.RecordTimesEditField.Enable = 'On';
            app.ReadRateHzEditField.Enable = 'On';
            app.TrigModeSwitch.Enable = 'On';
            app.DAQsListBox.Enable = 'On';
            app.sync.MasterButtonGroup.Enable = 'On';
            app.DoClockingCheckBox.Enable = 'On';
        end

        function loadState(app, loadfile)
            if ~exist(loadfile, 'file')
                return
            elseif ismember('state', who('-file', loadfile))
                warning('Not a valid state file!')
                return
            end
            state = load(loadfile, 'state');
            state = state.state;
            % Load value of important fields
            for i = 1:length(app.fieldsToSave)
                app.(app.fieldsToSave{i}).Value = state.(app.fieldsToSave{i}).Value;
            end
            % Load acquisition enable state
            app.ArmDataAcquisitionSwitch.Enable = state.ArmDataAcquisitionSwitch.Enable;
            app.FolderNote.Visible = state.FolderNote.Visible;
            % Load key values from channels, regenerate channel panels
            app.dn = state.dn;
            for i = state.channels.indices
                dq = app.dn{i};
                app.channels.(dq).howMany = state.channels.(dq).howMany;
                createChannelPanel(app, i, app.channels.(dq).howMany)
                % Update states for this panel
                for j = 1:app.channels.(dq).howMany
                    for k = app.channelFieldsToSave
                        app.channels.(dq).([k{1},num2str(j)]).Value = state.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                app.channels.(dq).DAQNameField.Value = state.channels.(dq).DAQNameField.Value;
                app.channels.(dq).TrigButtonGroup.Visible = state.channels.(dq).TrigButtonGroup.Visible;
            end
        end
        function saveState(app, savefile)
            state = struct();
            % Save value of important fields
            for i = 1:length(app.fieldsToSave)
                state.(app.fieldsToSave{i}).Value = app.(app.fieldsToSave{i}).Value;
            end
            % Save whether acquisition was enabled or not
            state.ArmDataAcquisitionSwitch.Enable = app.ArmDataAcquisitionSwitch.Enable;
            state.FolderNote.Visible = app.FolderNote.Visible;
            % Save key values from channels to allow regenerating channel panels
            state.dn = app.dn;
            state.channels.indices = find(~cellfun(@isempty, app.dn));
            state.channels.indexAvailable = app.channels.indexAvailable;
            % Loop over active channel panels
            for i = state.channels.indices
                dq = app.dn{i};
                state.channels.(dq).howMany = app.channels.(dq).howMany;
                % Loop over channels in this panel, save states
                for j = 1:state.channels.(dq).howMany
                    for k = app.channelFieldsToSave
                        state.channels.(dq).([k{1},num2str(j)]).Value = app.channels.(dq).([k{1},num2str(j)]).Value;
                    end
                end
                state.channels.(dq).DAQNameField.Value = app.channels.(dq).DAQNameField.Value;
                state.channels.(dq).TrigButtonGroup.Visible = app.channels.(dq).TrigButtonGroup.Visible;
            end
            save(savefile,'state');
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Positioning constants
            app.channels.trigOffset = 8; %px
            % Populate DAQs list box based on active and connected DAQs
            daqs = daqlist;
            app.DAQsListBox.Items = cellstr(daqs{:,2})';
%             app.DAQsListBox.Items = {'Dev1', 'Dev2', 'Dev3', 'Dev4'};
            % Create sync controls
            app.sync.MasterButtonGroup =  uibuttongroup(app.StartTab, ...
                'Position', [3, 0, 50, 200], 'Title', 'Master', 'AutoResizeChildren', 'off');
            % For the first connected+active DAQ, run channel panel creation
            app.DAQsListBox.Value = app.DAQsListBox.Items(1);
            app.dn = app.DAQsListBox.Items(1);
            createChannelPanel(app, 1, 10);
            app.channels.indexAvailable = [2];

            % Plot colors (assuming nobody will want to acquire and plot 100 channels)
            app.plt.colors = lines(100);

            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            folderName = uigetdir(pwd, 'Select directory for saving data');
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                %Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
                daqreset;
                % Declare Rates 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
                app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
                app.rate.read = round(app.rate.DAQ / app.ReadRateHzEditField.Value); %(samples)
                % Change looks of data acquisition switch
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
                % Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path, 'dir')
                    mkdir(app.name.projFolder, datestr(now,'yyyy_mm_dd'))
                end
                % Get which DAQ is clock and start trigger master
                masterDAQ = app.sync.MasterButtonGroup.SelectedObject.Tag;
                app.name.legendNames = {};
                % Loop over active DAQs
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    % Get which channels are active and should display
                    getChannelActiveState(app, i)
                    getChannelDisplayState(app, i)
                    % Initialize filename of this test
                    if ~isempty(app.FilenameoptionalEditField.Value)
                        app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', app.FilenameoptionalEditField.Value, '.mat'];
                    else
                        app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', char(datetime('now', 'format', 'yyyy_MM_dd')), '.mat'];
                    end
                    % Increment filename with numbers if same file already exists
                    if exist(fullfile(app.name.path, app.name.(i).filename), 'file')
                        % If no 3-digit number on end of file, add number
                        if isnan(str2double(app.name.(i).filename(end-6:end-4)))
                            app.name.(i).filename = [app.name.(i).filename(1:end-4), '_001', app.name.(i).filename(end-3:end)];
                            app.FilenameoptionalEditField.Value = app.name.(i).filename(1:end-4);
                        % Otherwise grab and increment number
                        else
                            number = str2double(app.name.(i).filename(end-6:end-4));
                            app.name.(i).filename = [app.name.(i).filename(1:end-6), num2str(number+1,'%03.f'), app.name.(i).filename(end-3:end)];
                            app.FilenameoptionalEditField.Value = app.name.(i).filename(1:end-4);
                        end
                    end
                    % Predeclare data
                    app.data.(i).time = nan(app.rate.buffer, 1);
                    app.data.(i).data = nan(app.rate.buffer, sum(app.channels.(i).active));
                    % Create DAQ session
                    app.s.(i) = daq.createSession('ni');
                    app.s.(i).Rate = app.rate.DAQ;
                    app.s.(i).NotifyWhenDataAvailableExceeds = app.rate.read;
                    app.s.(i).UserData = i;
                    % Create channels
                    app.s.(i).addAnalogInputChannel(i, find(app.channels.(i).active)-1, 'Voltage');
                    app.s.(i).IsContinuous = true;
                    % Create input/output listeners
                    app.lh.(i) = app.s.(i).addlistener('DataAvailable', @app.GrabData);
                    % If this DAQ is minion
                    if ~strcmp(i, masterDAQ) % TODO: This might be weak/vulnerable way to do this
                        addTriggerConnection(app.s.(i), 'External', [i, '/PFI', num2str(app.sync.(i).PFInum.Value)], 'StartTrigger');
                        if app.DoClockingCheckBox.Value
                            addClockConnection(app.s.(i), 'External', [i, '/PFI', num2str(app.sync.(i).clockPFInum.Value)], 'ScanClock');
                        end
                    % If this DAQ is master
                    else
                        addTriggerConnection(app.s.(i), [i, '/PFI', num2str(app.sync.(i).PFInum.Value)], 'External', 'StartTrigger');
                        if app.DoClockingCheckBox.Value
                            addClockConnection(app.s.(i), [i, '/PFI', num2str(app.sync.(i).clockPFInum.Value)], 'External', 'ScanClock');
                        end
                    end

                    % Disable fields that shouldn't change during recording
                    app.channels.(i).TrigButtonGroup.Enable = 'Off';
                    for j = 1:app.channels.(i).howMany
                        app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'Off';
                    end
                    app.channels.(i).NumberofChannelsEditField.Enable = 'Off';
                    app.channels.(i).DAQNameField.Enable = 'Off';
                    app.sync.(i).PFInum.Enable = 'Off';
                    app.sync.(i).clockPFInum.Enable = 'Off';
                    % Plot setup
                    for j = 1:app.channels.(i).howMany
                        % Create placeholder plot lines for each channel to update
                        app.channels.(i).(['line_',num2str(j)]) = line(app.mainPlot, nan, nan, 'color', app.plt.colors(j,:));
                        % Get name of this channel for legend
                        app.name.legendNames{end+1} = app.channels.(i).(['chanName_',num2str(j)]).Value;
                    end
                end
                % Disable fields that shouldn't change during recording
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.ReadRateHzEditField.Enable = 'Off';
                app.RecordTimesEditField.Enable = 'Off';
                app.TrigModeSwitch.Enable = 'Off';
                app.DAQsListBox.Enable = 'Off';
                app.sync.MasterButtonGroup.Enable = 'Off';
                app.DoClockingCheckBox.Enable = 'Off';

                % Create plot legend
                legend(app.mainPlot, app.name.legendNames, 'location', 'northwest')
                % Create plot timer
                app.rate.plot = app.PlotRateHzEditField.Value;
                app.plt.plotTimer = timer('TimerFcn', {@app.plotTimerUpdateFcn},...
                    'ExecutionMode', 'fixedRate', 'busymode', 'drop',...
                    'StartDelay', 0.2, 'Period', 1/app.rate.plot, 'Name', 'timer-1');
                start(app.plt.plotTimer);
                % Time x-axis stand-in to make plotting more visually appealing
                app.data.fakeTime = linspace(0, app.rate.plotLength, app.rate.buffer);
                
                % If minion DAQs, start them up and wait for all to be ready for trigger
                if length(app.DAQsListBox.Value) > 1
                    % Start minion DAQs
                    for dq = app.DAQsListBox.Value(~contains(app.DAQsListBox.Value, masterDAQ))
                        i = dq{1};
                        startBackground(app.s.(i));
                    end
                    % Pause to allow time for last minion to look for trigger
                    while ~app.s.(i).IsWaitingForExternalTrigger
                        pause(0.1)
                    end
                end
                % Start master DAQ
                startBackground(app.s.(masterDAQ));
           
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end

            % Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    stop(app.s.(i))
                end
                % Delete Listeners
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    delete(app.lh.(i))
                end
                % Stop timers
                if strcmp(get(app.plt.plotTimer,'Running'),'on')
                    stop(app.plt.plotTimer)
                end
            catch
            end
            
            delete(app)
        end

        % Value changed function: TrigModeSwitch
        function TrigModeChangedFcn(app, event)
            value = app.TrigModeSwitch.Value;
            app.TabGroup.SelectedTab = app.(value);
            if strcmp(value, 'ExtTrigTab')
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'on';
                end
            else
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'off';
                end
            end
        end

        % Callback function
        function ChangeNumChannelsFcn(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % If new # of channels less than current, remove some channels
            if value < app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                % TODO: Make actual references to channel panel dimensions
                % (rather than hard-coded 400 constant)
                % Loop over channels to remove
                for i = (value+1):app.channels.(app.dn{index}).howMany
                    delete(app.channels.(app.dn{index}).(['chanOn_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanName_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]))
                end
                resizeTrigButtons(app, app.dn{index}, value, value)
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
                
            % If new number more than current, add some channels
            elseif value > app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                resizeTrigButtons(app, app.dn{index}, app.channels.(app.dn{index}).howMany, value)
                % Loop over channels to create
                for i = (app.channels.(app.dn{index}).howMany+1):value
                    app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                        'Items', {'Off', 'On'}, 'Value', 'On', ...
                        'Position', [25, 308 - 25*(i-1), 23, 10]);
                    app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                        'Position', [78, 300 - 25*(i-1), 25, 25], ...
                        'Value', 1, 'Text', '');
                    app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                        'text', 'Value', ['ch',num2str(i)], ...
                        'Position', [102, 300 - 25*(i-1), 69, 20]);
                    app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(value-i), 16, 16], 'Text', '');
                end
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
            end
            % If same number of channels, do nothing
        end

        % Value changed function: DAQsListBox
        function NewDAQFcn(app, event)
            % Get removed DAQs, call remove channel function on them
            removedDAQs = event.PreviousValue(~contains(event.PreviousValue, event.Value));
            for i = 1:length(removedDAQs)
                % Delete
                delete(app.channelPanels.(removedDAQs{i}))
                delete(app.sync.(removedDAQs{i}).MasterButton)
                delete(app.sync.(removedDAQs{i}).PFInum)
                % clear out to allow new boxes to take this location
                index = find(strcmp(app.dn, removedDAQs(i)));
                app.dn{index} = '';
                app.channels.indexAvailable(end+1) = index;
            end
            
            % Get new DAQs, call create channel function on them
            newDAQs = event.Value(~contains(event.Value, event.PreviousValue));
            for i = 1:length(newDAQs)
                % If adding this channel panel to end
                if length(app.channels.indexAvailable) == 1
                    index = app.channels.indexAvailable(1);
                    app.channels.indexAvailable = app.channels.indexAvailable + 1;
                    app.dn{end+1} = newDAQs{i};
                % If putting in open middle slot
                else
                    sortAvailable = sort(app.channels.indexAvailable);
                    index = sortAvailable(1);
                    app.channels.indexAvailable(app.channels.indexAvailable==index) = [];
                    app.dn{index} = newDAQs{i};
                end
                % Create panel
                createChannelPanel(app, index, 10);
            end

        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Loop over number of channels, check or uncheck all
            for i = 1:app.channels.(app.dn{index}).howMany
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]).Value = value;
            end

        end

        % Menu selected function: OpenMenu
        function OpenMenuFcn(app, event)
            [file, path] = uigetfile('*.mat', 'Select valid file to load app state from');
            loadState(app, fullfile(path, file))
        end

        % Menu selected function: SaveMenu
        function SaveMenuFcn(app, event)
            [file, path] = uiputfile('*.mat', 'Select location and name to save app state file under');
            saveState(app, fullfile(path, file))
        end

        % Callback function
        function DAQNameValueChangedFcn(app, event)
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Change channel panels title
            app.channelPanels.(app.dn{index}).Title = [event.Value, ' channels'];
            % Change master button text
            app.sync.(app.dn{index}).MasterButton.Text = event.Value;
        end

        % Value changed function: DoClockingCheckBox
        function DoClockingValueChangedFcn(app, event)
            value = app.DoClockingCheckBox.Value;
            if value
                enableState = 'On';
            else
                enableState = 'Off';
            end
            for dq = app.DAQsListBox.Value
                i = dq{1};
                app.sync.(i).PFInum.Enable = enableState;
                app.sync.(i).clockPFInum.Enable = enableState;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenMenu = uimenu(app.UIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuFcn, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuFcn, true);
            app.SaveMenu.Text = 'Save';
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameoptionalEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameoptionalEditFieldLabel.Text = 'Filename (optional)';
            app.FilenameoptionalEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ReadRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.ReadRateHzEditFieldLabel.Text = 'Read Rate (Hz)';
            app.ReadRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.ReadRateHzEditField.Value = 5;
            app.TriggerControlsPanel = uipanel(app.UIFigure);
            app.TriggerControlsPanel.Title = 'Trigger Controls';
            app.TabGroup = uitabgroup(app.TriggerControlsPanel);
            app.ExtTrigTab = uitab(app.TabGroup);
            app.ExtTrigTab.Title = 'Ext Trig';
            app.SamplesposttriggerEditFieldLabel = uilabel(app.ExtTrigTab);
            app.SamplesposttriggerEditFieldLabel.Text = 'Samples post-trigger';
            app.SamplesposttriggerEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.SamplesposttriggerEditField.Value = 15;
            app.ClickTrigTab = uitab(app.TabGroup);
            app.ClickTrigTab.Title = 'Click Trig';
            app.TriggerButton = uibutton(app.ClickTrigTab, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TrigModeSwitch = uiswitch(app.TriggerControlsPanel, 'slider');
            app.TrigModeSwitch.Items = {'External', 'Click Button'};
            app.TrigModeSwitch.ItemsData = {'ExtTrigTab', 'ClickTrigTab'};
            app.TrigModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrigModeChangedFcn, true);
            app.TrigModeSwitch.Value = 'ExtTrigTab';
            app.ArmDataAcquisitionPanel = uipanel(app.UIFigure);
            app.ArmDataAcquisitionPanel.Title = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.ArmDataAcquisitionPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.DAQsListBoxLabel = uilabel(app.UIFigure);
            app.DAQsListBoxLabel.Text = 'DAQs';
            app.DAQsListBox = uilistbox(app.UIFigure);
            app.DAQsListBox.Items = {};
            app.DAQsListBox.Multiselect = 'on';
            app.DAQsListBox.ValueChangedFcn = createCallbackFcn(app, @NewDAQFcn, true);
            app.DAQsListBox.Value = {};
            app.SynchronizationPanel = uipanel(app.UIFigure);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.TabGroup2 = uitabgroup(app.SynchronizationPanel);
            app.StartTab = uitab(app.TabGroup2);
            app.StartTab.Title = 'Start';
            app.PFIchannelLabel = uilabel(app.StartTab);
            app.PFIchannelLabel.Text = 'PFI channel #';
            app.ClockingTab = uitab(app.TabGroup2);
            app.ClockingTab.Title = 'Clocking';
            app.DoClockingCheckBox = uicheckbox(app.ClockingTab);
            app.DoClockingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoClockingValueChangedFcn, true);
            app.DoClockingCheckBox.Text = '';
            app.DoClockingCheckBox.Value = true;
            app.SyncDAQsviaclockfromamasterDAQLabel = uilabel(app.ClockingTab);
            app.SyncDAQsviaclockfromamasterDAQLabel.Text = {'Sync DAQs via clock '; 'from a master DAQ'};
            app.PFIchannelLabel_2 = uilabel(app.ClockingTab);
            app.PFIchannelLabel_2.Text = 'PFI channel #';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end